classdef M_SLIP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SLIP                            matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        ExtraMenu                       matlab.ui.container.Menu
        RastersMenu                     matlab.ui.container.Menu
        MergeDEMsMenu                   matlab.ui.container.Menu
        ForestMapMenu                   matlab.ui.container.Menu
        IntersectionextrapropertiesMenu  matlab.ui.container.Menu
        ExtraMorphologyMenu             matlab.ui.container.Menu
        CurvaturesMenu                  matlab.ui.container.Menu
        HydrologyMenu                   matlab.ui.container.Menu
        UpslopeAreaTWIMenu              matlab.ui.container.Menu
        HRUsMenu                        matlab.ui.container.Menu
        DatabaseMenu                    matlab.ui.container.Menu
        LandslidesPolygonsMenu          matlab.ui.container.Menu
        PointsAssociationMenu           matlab.ui.container.Menu
        PointsInfoMenu                  matlab.ui.container.Menu
        AverageValuesMenu               matlab.ui.container.Menu
        DatasetCreationMenu             matlab.ui.container.Menu
        DatasetStatisticsMenu           matlab.ui.container.Menu
        ModelingMenu                    matlab.ui.container.Menu
        SLIPMenu                        matlab.ui.container.Menu
        HandinitialSrMenu               matlab.ui.container.Menu
        mEvaluationMenu                 matlab.ui.container.Menu
        FSevaluationMenu                matlab.ui.container.Menu
        MachineLearningMenu             matlab.ui.container.Menu
        LEPAIMenu                       matlab.ui.container.Menu
        PIPAIGenericMenu                matlab.ui.container.Menu
        PIPAIANNMenu                    matlab.ui.container.Menu
        PostModelingMenu                matlab.ui.container.Menu
        MLAnalysisMenu                  matlab.ui.container.Menu
        ApplyLEPAIMenu                  matlab.ui.container.Menu
        ApplyPIPAIMenu                  matlab.ui.container.Menu
        HybridAnalysesMenu              matlab.ui.container.Menu
        MLSLIPMenu                      matlab.ui.container.Menu
        PerformanceMenu                 matlab.ui.container.Menu
        PerfAUCMenu                     matlab.ui.container.Menu
        PerfMLMetricsMenu               matlab.ui.container.Menu
        PPRvsNPRMenu                    matlab.ui.container.Menu
        TimeIndexMenu                   matlab.ui.container.Menu
        RiskMenu                        matlab.ui.container.Menu
        SusceptibilityMenu              matlab.ui.container.Menu
        RiskAssessmentMenu              matlab.ui.container.Menu
        XAIMenu                         matlab.ui.container.Menu
        FeaturesImportanceMenu          matlab.ui.container.Menu
        PartialDependenceMenu           matlab.ui.container.Menu
        EvolutionMenu                   matlab.ui.container.Menu
        LandslidePathsMenu              matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        PlotSettingsMenu                matlab.ui.container.Menu
        StudyAreaMenu                   matlab.ui.container.Menu
        MunicipalitiesMenu              matlab.ui.container.Menu
        OrthophotoMenu                  matlab.ui.container.Menu
        LandUseMenu                     matlab.ui.container.Menu
        AllMenu                         matlab.ui.container.Menu
        ExcludedMenu                    matlab.ui.container.Menu
        MorphologyMenu                  matlab.ui.container.Menu
        ElevationMenu                   matlab.ui.container.Menu
        SlopeMenu                       matlab.ui.container.Menu
        AspectMenu                      matlab.ui.container.Menu
        SoilMenu                        matlab.ui.container.Menu
        LithologyMapMenu                matlab.ui.container.Menu
        EffectiveCohesionMenu           matlab.ui.container.Menu
        FrictionAngleMenu               matlab.ui.container.Menu
        DrainageCoefficientMenu         matlab.ui.container.Menu
        AParameterMenu                  matlab.ui.container.Menu
        SoilUnitsMenu                   matlab.ui.container.Menu
        VegetationMenu                  matlab.ui.container.Menu
        MapMenu                         matlab.ui.container.Menu
        RootcohesionMenu                matlab.ui.container.Menu
        ThroughfallMenu                 matlab.ui.container.Menu
        DetectedMenu                    matlab.ui.container.Menu
        SoilPropertiesMenu              matlab.ui.container.Menu
        RainfallMenu                    matlab.ui.container.Menu
        RecordingsMenu                  matlab.ui.container.Menu
        RecordingMapMenu                matlab.ui.container.Menu
        RecordingTrendMenu              matlab.ui.container.Menu
        ForecastMapMenu                 matlab.ui.container.Menu
        PlotPerformanceMenu             matlab.ui.container.Menu
        AUCMenu                         matlab.ui.container.Menu
        MLMetricsMenu                   matlab.ui.container.Menu
        DistanceMenu                    matlab.ui.container.Menu
        RoadMenu                        matlab.ui.container.Menu
        SpecificLUMenu                  matlab.ui.container.Menu
        FSMenu                          matlab.ui.container.Menu
        FSMap                           matlab.ui.container.Menu
        PunctualMenu                    matlab.ui.container.Menu
        MLMenu                          matlab.ui.container.Menu
        FeatureImportanceMenu           matlab.ui.container.Menu
        FeaturesCorrelationMenu         matlab.ui.container.Menu
        PredictionsMenu                 matlab.ui.container.Menu
        PDPMenu                         matlab.ui.container.Menu
        PlotHydrologyMenu               matlab.ui.container.Menu
        PlotHRUsMenu                    matlab.ui.container.Menu
        EvolutionMenu_2                 matlab.ui.container.Menu
        PathsMenu                       matlab.ui.container.Menu
        DISTANCESTOPOLYGONSPanel        matlab.ui.container.Panel
        LampDist                        matlab.ui.control.Lamp
        FromLandUseButton               matlab.ui.control.Button
        EvaluateButton                  matlab.ui.control.Button
        SelectfileDropDown              matlab.ui.control.DropDown
        SelectfileDropDownLabel         matlab.ui.control.Label
        UITableDstPoly                  matlab.ui.control.Table
        UITableRainfall                 matlab.ui.control.Table
        UITableVeg                      matlab.ui.control.Table
        STARTButton                     matlab.ui.control.Button
        FolderLabel                     matlab.ui.control.Label
        CasestudyLabel                  matlab.ui.control.Label
        UITableLitho                    matlab.ui.control.Table
        UITableDTM                      matlab.ui.control.Table
        UITableLURem                    matlab.ui.control.Table
        UITableMun                      matlab.ui.control.Table
        TIMESENSITIVERAINFALLETCPanel   matlab.ui.container.Panel
        TSLampInterp                    matlab.ui.control.Lamp
        EventsButton                    matlab.ui.control.Button
        SwitchTypeInterp                matlab.ui.control.Switch
        LampInterp                      matlab.ui.control.Lamp
        InterpolationButton             matlab.ui.control.Button
        SelectfilesButton               matlab.ui.control.Button
        ForecastCheckBox                matlab.ui.control.CheckBox
        RecordsCheckBox                 matlab.ui.control.CheckBox
        SwitchRain                      matlab.ui.control.Switch
        VEGETATIONPARAMETERSPanel       matlab.ui.container.ButtonGroup
        AssociationButtonVeg            matlab.ui.control.Button
        FinishedLampVeg                 matlab.ui.control.Lamp
        RemAssButton                    matlab.ui.control.Button
        ChoosefileVegDropDown           matlab.ui.control.DropDown
        SelectfileLabel_3               matlab.ui.control.Label
        SelectFieldVegDropDown          matlab.ui.control.DropDown
        SelectFieldDropDown_2Label      matlab.ui.control.Label
        ExcelcreatedLabelVeg            matlab.ui.control.Label
        RunVeg                          matlab.ui.control.Button
        LandUseCheckBox                 matlab.ui.control.CheckBox
        SwitchVeg                       matlab.ui.control.Switch
        MORPHOLOGYPanel                 matlab.ui.container.Panel
        LampMorph                       matlab.ui.control.Lamp
        RunDTMButton                    matlab.ui.control.Button
        OrthophotoCheckBox              matlab.ui.control.CheckBox
        SelectDTMfilesButton            matlab.ui.control.Button
        NewResEditField                 matlab.ui.control.NumericEditField
        EnterResolutionLabel            matlab.ui.control.Label
        ChangeResolutionCheckBox        matlab.ui.control.CheckBox
        LANDUSEPanel                    matlab.ui.container.Panel
        LUFinishedLamp                  matlab.ui.control.Lamp
        SelectButton                    matlab.ui.control.Button
        FinishedLamp                    matlab.ui.control.Lamp
        ImportLUButton                  matlab.ui.control.Button
        FieldLandUseDropDown            matlab.ui.control.DropDown
        FieldLandUseDropDownLabel       matlab.ui.control.Label
        SelectfileLUDropDown            matlab.ui.control.DropDown
        SelectfileLabel                 matlab.ui.control.Label
        STUDYAREAPanel                  matlab.ui.container.Panel
        WinCheckBox                     matlab.ui.control.CheckBox
        FinishedLampRun1                matlab.ui.control.Lamp
        SelMunButton                    matlab.ui.control.Button
        RunSAButton                     matlab.ui.control.Button
        FieldMunicipalityDropDown       matlab.ui.control.DropDown
        FieldMunicipalityDropDownLabel  matlab.ui.control.Label
        SelectfileSADropDown            matlab.ui.control.DropDown
        StudyAreaLabel                  matlab.ui.control.Label
        SOILPARAMETERSButtonGroup       matlab.ui.container.ButtonGroup
        FinishedLampSoil1               matlab.ui.control.Lamp
        ChoosefileLitDropDown           matlab.ui.control.DropDown
        SelectfileLabel_2               matlab.ui.control.Label
        AssociationButton               matlab.ui.control.Button
        RunLito                         matlab.ui.control.Button
        ExcelcreatedLabel               matlab.ui.control.Label
        SelectFieldDropDown             matlab.ui.control.DropDown
        SelectFieldDropDownLabel        matlab.ui.control.Label
        SwitchSoil                      matlab.ui.control.Switch
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            try
                CaseName = string(inputdlg2({'Case study name:'}, 'DefInp',{'Municipality - Year'}));
            
                app.CasestudyLabel.Text = strcat("Case study: ",CaseName);
                app.FolderLabel.Text    = uigetdir('open');
            
                cd(app.FolderLabel.Text)

                figure(app.SLIP)
                drawnow
            
                a_FolderCreation
            
                app.STARTButton.Enable = 'on';
            
                uialert(app.SLIP, ['Please fill the Raw Data subfolders (',fold_raw,')'], 'Warning')
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end
            
            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')
            
            app.SelectfileSADropDown.Enable = 'on';
            app.StudyAreaLabel.Enable       = 'on';
            app.WinCheckBox.Enable          = 'on';
        end

        % Callback function: SelectfileSADropDown, SelectfileSADropDown
        function SelectfileSADropDownOpening(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_raw_mun')
            
            cd(fold_raw_mun)
            if isempty({dir('*.shp').name})
                StArAns = uiconfirm(app.SLIP, ['No Study Area shapefile in ',fold_raw_mun], ...
                                         'No file in directory', 'Options',{'Search file', 'Manual coords'});
                if strcmp(StArAns, 'Search file')
                    copyindirectory('shp', fold_raw_mun, 'mode','multiple')
                else
                    app.WinCheckBox.Value  = true;
                    app.RunSAButton.Enable = 'on';
                end
            end

            try
                app.FieldMunicipalityDropDown.Enable      = 'on';
                app.FieldMunicipalityDropDownLabel.Enable = 'on';

                FilesMun = {dir('*.shp').name, 'None'};
                app.SelectfileSADropDown.Items = FilesMun;
                ChsnSAFilename = app.SelectfileSADropDown.Value;
                if not(strcmp(ChsnSAFilename, 'None'))
                    MunShapeFields = string( [{shapeinfo(ChsnSAFilename).Attributes.Name}, {"None"}] );
                    app.FieldMunicipalityDropDown.Items = MunShapeFields;
                end

            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: SelMunButton
        function SelMunButtonPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_raw_mun')    

            cd(fold_raw_mun);
            MunShape     = shaperead(app.SelectfileSADropDown.Value);
            MunFieldName = app.FieldMunicipalityDropDown.Value;
            MunOpt       = unique({MunShape.(MunFieldName)});
            IndMun       = checkbox2(MunOpt, 'Title',{'Choose municipalities:'}, 'OutType','NumInd');
            MunSel       = MunOpt(IndMun);
            set(app.UITableMun, 'Data',MunSel')

            figure(app.SLIP)
            drawnow

            app.RunSAButton.Enable = 'on';
        end

        % Button pushed function: RunSAButton
        function RunSAButtonPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')

            Fig = app.SLIP;
            
            FileName_StudyArea = app.SelectfileSADropDown.Value;
            MunFieldName = app.FieldMunicipalityDropDown.Value;

            if strcmp(MunFieldName, 'None')
                MunSel = {'None'};
            else
                MunTmp = get(app.UITableMun, 'Data');
                MunSel = reshape(MunTmp, numel(MunTmp), 1);
            end

            SpecificWindow = app.WinCheckBox.Value;

            cd(app.FolderLabel.Text)
            try 
                A_StudyArea_Step1
    
                app.SelectfileLabel.Enable           = 'on';
                app.SelectfileLUDropDown.Enable      = 'on';
                app.FieldLandUseDropDownLabel.Enable = 'on';
                app.FieldLandUseDropDown.Enable      = 'on';
                app.FinishedLampRun1.Visible         = 'on';           
                app.SelectDTMfilesButton.Enable      = 'on';
                app.StudyAreaMenu.Enable             = 'on';
                app.OrthophotoCheckBox.Enable        = 'on';

            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Callback function: SelectfileLUDropDown, SelectfileLUDropDown
        function SelectfileLUDropDownOpening(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_raw_land_uses') 

            cd(fold_raw_land_uses)
            if isempty({dir('*.shp').name})
                Ans = uiconfirm(app.SLIP, strcat("No Land Uses shapefile in ",fold_raw_land_uses), ...
                                          'No file in directory', 'Options','Search file');
                copyindirectory('shp', fold_raw_land_uses, 'mode','multiple')
            end

            try
                app.FieldLandUseDropDown.Enable      = 'on';
                app.FieldLandUseDropDownLabel.Enable = 'on';
                app.FinishedLamp.Visible             = 'on';
                app.ImportLUButton.Enable            = 'on';

                FilesLand = {dir('*.shp').name};
                app.SelectfileLUDropDown.Items = FilesLand;

                ChsnLandUseFile = app.SelectfileLUDropDown.Value;
                LndUseShpFields = string( [{shapeinfo(ChsnLandUseFile).Attributes.Name}, {'None'}] );
                app.FieldLandUseDropDown.Items = LndUseShpFields;

            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: ImportLUButton
        function ImportLUButtonPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')

            Fig = app.SLIP;
                       
            FileNameLandUses  = app.SelectfileLUDropDown.Value;
            LandUsesFieldName = app.FieldLandUseDropDown.Value;

            try
                A_StudyArea_Step2
      
                app.LandUseMenu.Enable          = 'on';
                app.AllMenu.Enable              = 'on';
                app.SelectButton.Enable         = 'on';
                app.SelectDTMfilesButton.Enable = 'on';
                app.FinishedLamp.Color          = [0, 1, 0];

                uialert(app.SLIP, ['Please fill LandUsesAssociation.xlsx', ...
                                   ' ([User Control] folder)'], 'User Control')

            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: SelectButton
        function RemoveLUButtonPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold_user')

            Fig = app.SLIP;

            sl = filesep;
            load([fold_var,sl,'LandUsesVariables'], 'AllLandUnique')

            try
                SheetLuAss = readcell([fold_user,sl,'LandUsesAssociation.xlsx'], 'Sheet','Association');
                AllLndUnAb = SheetLuAss(2:end,2)';

                if any(cellfun(@(x) all(ismissing(x)), AllLndUnAb))
                    uialert(app.SLIP, ['The Abbreviation column of ', ...
                            'LandUsesAssociation.xlsx ([User Control] ', ...
                            'folder) is not filled!'], 'User Control Error')
                end

                LndUse2RemSel = checkbox2(AllLandUnique, 'Title',{'Land uses to remove:'});

                if isempty(LndUse2RemSel)
                    warning('No land use to remove selected!')
                else     
                    set(app.UITableLURem, 'Data',LndUse2RemSel');
        
                    A_StudyArea_Step3
        
                    app.LUFinishedLamp.Visible = 'on';
                    app.ExcludedMenu.Enable    = 'on';
                end

            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end
        end

        % Button pushed function: SelectDTMfilesButton
        function SelectDTMfilesButtonPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_raw_dtm')
            
            cd(fold_raw_dtm)

            if isempty([{dir('*.tif').name}, {dir('*.asc').name},{dir('*.img').name}])
                Ans = uiconfirm(app.SLIP, strcat("No rasters in ",fold_raw_dtm), ...
                                'No file in directory', 'Options','Search file');
                copyindirectory({'tif','tfw','prj','asc'}, fold_raw_dtm, 'mode','multiple')
            end

            try
                Files = sort(string([{dir('*.tif').name}, ...
                                     {dir('*.tfw').name}, ...
                                     {dir('*.prj').name}, ...
                                     {dir('*.asc').name}, ...
                                     {dir('*.img').name}]));

                FileName_DTM = string(checkbox2(Files, 'Title',{'Choose DEM file(s):'}));
                set(app.UITableDTM, 'Data',FileName_DTM');
                
                app.ChangeResolutionCheckBox.Enable = 'on';
                app.RunDTMButton.Enable             = 'on';
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Value changed function: ChangeResolutionCheckBox
        function ChangeResolutionCheckBoxValueChanged(app, event)
            ChangeRes = app.ChangeResolutionCheckBox.Value;
            if ChangeRes == 1
                app.NewResEditField.Editable = 'on';
                app.NewResEditField.Enable   = 'on';
            else
                app.NewResEditField.Editable = 'off';
                app.NewResEditField.Enable   = 'off';
            end
        end

        % Button pushed function: RunDTMButton
        function RunDTMButtonPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')

            Fig = app.SLIP;
            
            cd(fold_var)
            if exist('UserMorph_Answers.mat', 'file')
                Answer = uiconfirm(app.SLIP, ...
                                   ['DTM has been already processed. ' ...
                                    'Do you want to overwrite existing data?'], ...
                                   'Existing DTM data', 'Options',{'Yes, thanks', 'No, for God!'}, ...
                                   'DefaultOption',2);
                switch Answer
                    case 'Yes, thanks'
                        
                    case 'No, for God!'
                        load('UserMorph_Answers.mat')           

                        set(app.UITableDTM, 'Data',FileName_DTM);
                        
                        if AnswerChangeDTMResolution == 1
                            app.ChangeResolutionCheckBox.Enable = 'on';
                            app.NewResEditField.Enable          = 'on';
                            app.NewResEditField.Editable        = 'on';
                            app.ChangeResolutionCheckBox.Value  = 1;
                            app.NewResEditField.Value           = NewDx;
                        end

                        return                        
                end
            end
            
            AnswerChangeDTMResolution = app.ChangeResolutionCheckBox.Value;
            FileName_DTM = get(app.UITableDTM,'Data');

            if any(contains(FileName_DTM,'tif')) && any(contains(FileName_DTM,'tfw'))
                DTMType = 0;
            else
                DTMType = 1; % Remember that you should implement other Types
            end

            if AnswerChangeDTMResolution == 1
                NewDx = app.NewResEditField.Value;
                NewDy = NewDx;
            end

            OrthophotoAnswer = app.OrthophotoCheckBox.Value;

            try
                B_Morphology
    
                app.LampMorph.Visible     = 'on';
                app.MorphologyMenu.Enable = 'on';
                app.ElevationMenu.Enable  = 'on';
                app.SlopeMenu.Enable      = 'on';
                app.AspectMenu.Enable     = 'on';  
                app.SwitchSoil.Enable     = 'on';
                app.RunLito.Enable        = 'on';

            catch me
                 getReport(me)
                 uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                    'an error occurred!'], 'Generic Error')
            end

            try
                cd(fold_raw_road)
                app.SelectfileDropDown.Enable  = 'on';
                app.EvaluateButton.Enable    = 'on';
                app.FromLandUseButton.Enable = 'on';

                FilesRoad = {dir('*.shp').name};
                app.SelectfileDropDown.Items = FilesRoad;

            catch me
                 getReport(me)
                 uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                    'an error occurred!'], 'Generic Error')
            end
            
            figure(app.SLIP)
            drawnow
        end

        % Value changed function: SwitchSoil
        function SwitchSoilValueChanged(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_raw_lit')
            
            SwitchSoilValue = app.SwitchSoil.Value;
            if strcmp(SwitchSoilValue, 'Map')
               app.ChoosefileLitDropDown.Enable = 'on';
               app.SelectFieldDropDown.Enable   = 'on';
            elseif strcmp(SwitchSoilValue, 'Uniform')
                app.ChoosefileLitDropDown.Enable = 'off';
                app.SelectFieldDropDown.Enable   = 'off';
                app.ExcelcreatedLabel.Visible    = 'off';
                app.AssociationButton.Visible    = 'off';
            else
                uialert(app.SLIP, ['Error in soil parameters type switch, ' ...
                                   'not "Map" or "Uniform"'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Callback function: ChoosefileLitDropDown, ChoosefileLitDropDown
        function ChoosefileLitDropDownOpening(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_raw_lit')

            cd(fold_raw_lit)
            if isempty({dir('*.shp').name})
                Ans = uiconfirm(app.SLIP, strcat("No litho shapefile in ",fold_raw_lit), ...
                                          'No file in directory', 'Options','Search file');
                copyindirectory('shp', fold_raw_lit, 'mode','multiple')
            end

            try
                FilesLit = {dir('*.shp').name};
                app.ChoosefileLitDropDown.Items = FilesLit;
                app.SelectFieldDropDown.Enable  = 'on';

                ChosedLitFilename = app.ChoosefileLitDropDown.Value;
                LitShapeFields = string( [{shapeinfo(ChosedLitFilename).Attributes.Name}, {"None of these"}] );
                app.SelectFieldDropDown.Items  = LitShapeFields;

            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: RunLito
        function RunLitoPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_raw_lit','fold_var','fold_user','fold0')

            app.FinishedLampSoil1.Visible = 'on';

            Fig = app.SLIP;
            
            if strcmp(app.SwitchSoil.Value, 'Uniform')
                AnswerAttributionSoilParameter = 0;

                try
                    C_Soil_ParametersAttribution_Step0
                catch me
                    getReport(me)
                    uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                       'an error occurred!'], 'Generic Error')
                end

                app.SoilMenu.Enable                  = 'on';
                app.FrictionAngleMenu.Enable         = 'on';
                app.EffectiveCohesionMenu.Enable     = 'on';
                app.DrainageCoefficientMenu.Enable   = 'on';
                app.AParameterMenu.Enable            = 'on';
                app.SwitchVeg.Enable                 = 'on';
                app.VEGETATIONPARAMETERSPanel.Enable = 'on';

            elseif strcmp(app.SwitchSoil.Value, 'Map')
                AnswerAttributionSoilParameter = 1;
                
                FileName_Lithology = app.ChoosefileLitDropDown.Value;
                LitFieldName       = app.SelectFieldDropDown.Value;
                try
                    C_Soil_ParametersAttribution_Step1

                    uialert(app.SLIP, ['Please fill LuDSCAssociation.xlsx', ...
                                       ' ([User Control] folder)'], 'User Control')
                catch me
                    getReport(me)
                    uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                       'an error occurred!'], 'Generic Error')
                end

                app.ExcelcreatedLabel.Visible = 'on';
                app.AssociationButton.Visible = 'on';             
            end

            app.FinishedLampSoil1.Color = [0 1 0];

            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: AssociationButton
        function AssociationButtonLithoPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold_user','fold0')

            Fig = app.SLIP;

            try
                C_Soil_ParametersAttribution_Step2

                app.SoilMenu.Enable                  = 'on';
                app.LithologyMapMenu.Enable          = 'on';
                app.SoilUnitsMenu.Enable             = 'on';
                app.FrictionAngleMenu.Enable         = 'on';
                app.EffectiveCohesionMenu.Enable     = 'on';
                app.DrainageCoefficientMenu.Enable   = 'on';
                app.AParameterMenu.Enable            = 'on';
                app.SwitchVeg.Enable                 = 'on';
                app.VEGETATIONPARAMETERSPanel.Enable = 'on';

                LithoStrings = [SoilAssociation{:, 'Acronym'}, ...
                                string(SoilAssociation{:, 'UC'})];
                set(app.UITableLitho, 'Data',LithoStrings)

            catch me
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'],'Generic Error')
                getReport(me)
            end

            figure(app.SLIP)
            drawnow
        end

        % Value changed function: SwitchVeg
        function SwitchVegValueChanged(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_raw_veg')
            
            app.LandUseCheckBox.Value = 0;        
            SwitchVegValue = app.SwitchVeg.Value;         
            if strcmp(SwitchVegValue, 'Map')
               app.LandUseCheckBox.Enable        = 'on';
               app.ChoosefileVegDropDown.Enable  = 'on';
               app.SelectFieldVegDropDown.Enable = 'on';
            else
               app.ChoosefileVegDropDown.Visible  = 'off';
               app.SelectFieldVegDropDown.Visible = 'off';
               app.LandUseCheckBox.Enable         = 'off';
            end
        end

        % Value changed function: LandUseCheckBox
        function LandUseCheckBoxValueChanged(app, event)
            LandUse4Veg = app.LandUseCheckBox.Value;
            if LandUse4Veg == 1
                app.ChoosefileVegDropDown.Enable  = 'off';
                app.SelectFieldVegDropDown.Enable = 'off';
                app.SelectFieldVegDropDown.Items  = {app.FieldLandUseDropDown.Value};
                app.ChoosefileVegDropDown.Items   = {app.SelectfileLUDropDown.Value};
            end
        end

        % Callback function: ChoosefileVegDropDown, ChoosefileVegDropDown
        function ChoosefileVegDropDownOpening(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_raw_veg')
            
            cd(fold_raw_veg)

            if isempty({dir('*.shp').name})
                Ans = uiconfirm(app.SLIP, strcat("No vegetation shapefile in ",fold_raw_veg), ...
                                          'No file in directory', 'Options','Search file');
                copyindirectory('shp', fold_raw_veg, 'mode','multiple')
            end

            try
                if not(app.LandUseCheckBox.Value)
                    FilesVeg = {dir('*.shp').name};
                    app.ChoosefileVegDropDown.Items = FilesVeg;
                end

                app.SelectFieldVegDropDown.Enable = 'on';
                ChosedFilename = app.ChoosefileVegDropDown.Value;
                VegShapeFields = string( [{shapeinfo(ChosedFilename).Attributes.Name}, {"None of these"}] );
                app.SelectFieldVegDropDown.Items = VegShapeFields;
            catch me
                getReport(me)
                uialert(app.SLIP,'Please see MATLAB command, an error occurred!','Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: RunVeg
        function RunVegPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')

            Fig = app.SLIP;

            app.FinishedLampVeg.Visible = 'on';
            if strcmp(app.SwitchVeg.Value, 'Uniform')
                AnswerAttributionVegetationParameter = 0;
                C_Vegetation_ParameterAttribution_Step0

                app.VegetationMenu.Enable   = 'on';
                app.RootcohesionMenu.Enable = 'on';
                app.ThroughfallMenu.Enable  = 'on';

            elseif strcmp(app.SwitchVeg.Value, 'Map')
                if app.LandUseCheckBox.Value == 1
                    FileName_Vegetation = app.SelectfileLUDropDown.Value;

                    AnswerAttributionVegetationParameter = 2;
                    C_Vegetation_ParameterAttribution_Step1_LU
                else
                    FileName_Vegetation = app.ChoosefileVegDropDown.Value;                                   
                    VegFieldName        = app.SelectFieldVegDropDown.Value;

                    AnswerAttributionVegetationParameter = 1;
                    C_Vegetation_ParameterAttribution_Step1
                end

                uialert(app.SLIP, ['Please fill VuDVCAssociation.xlsx', ...
                                   ' ([User Control] folder)'], 'User Control')

                app.ExcelcreatedLabelVeg.Visible = 'on';
                app.AssociationButtonVeg.Enable  = 'on';
                app.VegetationMenu.Enable        = 'on';
                app.MapMenu.Enable               = 'on';
            end
            app.FinishedLampVeg.Color = [0 1 0];
        end

        % Button pushed function: AssociationButtonVeg
        function AssociationButtonVegPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold_user','fold0')

            Fig = app.SLIP;

            try
                C_Vegetation_ParameterAttribution_Step2

                app.RootcohesionMenu.Enable = 'on';
                app.ThroughfallMenu.Enable  = 'on';

                VegStr = [VegAssociation{:, 'Acronym'}, ...
                          string(VegAssociation{:, 'UC'})];
                set(app.UITableVeg, 'Data',VegStr);

            catch me
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'],'Generic Error')
                getReport(me)
            end
            
            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: ElevationMenu
        function ElevationMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold0','fold_fig')

            Fig = app.SLIP;

            NumFigPlot=1;
            xyz_Plot_Map_Morphology
        end

        % Menu selected function: SlopeMenu
        function SlopeMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold0','fold_fig')

            Fig = app.SLIP;

            NumFigPlot=2;
            xyz_Plot_Map_Morphology
        end

        % Menu selected function: AspectMenu
        function AspectMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold0','fold_fig')

            Fig = app.SLIP;

            NumFigPlot = 3;
            xyz_Plot_Map_Morphology
        end

        % Menu selected function: AllMenu
        function AllMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')

            Fig = app.SLIP;

            NumFigPlot = 1;
            xyz_Plot_Map_LandUse
        end

        % Menu selected function: ExcludedMenu
        function ExcludedMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')

            Fig = app.SLIP;

            NumFigPlot = 2;
            xyz_Plot_Map_LandUse
        end

        % Menu selected function: LithologyMapMenu
        function LithologyMapMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_fig')

            Fig = app.SLIP;

            NumFigPlot = 1;
            xyz_Plot_Map_SoilParameters
        end

        % Menu selected function: EffectiveCohesionMenu
        function EffectiveCohesionMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_fig')

            Fig = app.SLIP;

            NumFigPlot = 2;
            xyz_Plot_Map_SoilParameters         
        end

        % Menu selected function: FrictionAngleMenu
        function FrictionAngleMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_fig')

            Fig = app.SLIP;

            NumFigPlot = 3;
            xyz_Plot_Map_SoilParameters
        end

        % Value changed function: FieldMunicipalityDropDown
        function FieldMunicipalityDropDownValueChanged(app, event)
            MunFieldName = app.FieldMunicipalityDropDown.Value;
            if strcmp(MunFieldName, 'None')
                app.SelMunButton.Enable = 'off';
                app.RunSAButton.Enable  = 'on';
            else
                app.SelMunButton.Enable = 'on';
            end
        end

        % Button pushed function: InterpolationButton
        function InterpolationButtonPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold0','sl')

            Fig = app.SLIP;

            InterpDuration = app.SwitchTypeInterp.Value;
            AnalysisCase   = app.SwitchRain.Value;
            try 
                if strcmp(InterpDuration, 'Daily')
                    D_TimeSensitive_Step2_InterpolationDaily
                else
                    D_TimeSensitive_Step2_Interpolation
                end

                app.LampInterp.Visible  = 'on';
                app.RainfallMenu.Enable = 'on'; 
            
                if strcmp(AnalysisCase ,'SLIP')
                    app.SLIPMenu.Enable          = 'on';
                    app.HandinitialSrMenu.Enable = 'on';
                    app.mEvaluationMenu.Enable   = 'on';
                end      
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: HandinitialSrMenu
        function HandinitialSrMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders','fold_var')

            cd(fold_var)
            load('UserTimeSens_Answers.mat')
            
            if exist('Sr0', 'var')
                SuggestedValue = {num2str(Sr0), num2str(H)};
            else
                SuggestedValue = {'0.55','1.2'};
            end

            % Attribution of Sr0 and H
            InputParam = inputdlg2({'Initial saturation Sr0 (-):', ...
                                    'Depth of topsoil H (meters):'}, 'DefInp',SuggestedValue);
            Sr0 = str2double(InputParam{1});
            H   = str2double(InputParam{2});

            % Creating cell array with name of variables that will be stored
            save('UserTimeSens_Answers.mat', 'Sr0','H','-append')

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: mEvaluationMenu
        function mEvaluationMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')

            Fig = app.SLIP;

            try
                E_SLIP_Step1_mEvaluation
                app.FSevaluationMenu.Enable = 'on';
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: FSevaluationMenu
        function FSevaluationMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold0','fold_res_fs','sl')

            Fig = app.SLIP;

            try
                E_SLIP_Step2_FS
                app.PIPAIGenericMenu.Enable = 'on';
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            app.FolderLabel.Text = uigetdir('open');
            cd(app.FolderLabel.Text)

            figure(app.SLIP)
            drawnow
            
            load('os_folders', 'CaseName')
            a_FolderCreation
            
            app.CasestudyLabel.Text = strcat("Case study: ",CaseName);
            app.STARTButton.Enable  = true;

            cd(fold_var)
            
            if exist('UserStudyArea_Answers.mat', 'file')
                load('UserStudyArea_Answers.mat')
                cd(fold_raw_mun)
                
                app.STUDYAREAPanel.Enable                 = 'on';
                app.SelectfileSADropDown.Enable           = 'on';
                app.WinCheckBox.Enable                    = 'on';
                app.StudyAreaMenu.Enable                  = 'on';
                app.FieldMunicipalityDropDown.Enable      = 'on';
                app.FieldMunicipalityDropDownLabel.Enable = 'on';
                app.SelMunButton.Enable                   = 'on';
                app.RunSAButton.Enable                    = 'on';
                app.WinCheckBox.Value                     = SpecificWindow;

                FilesMun = {dir('*.shp').name};
                app.SelectfileSADropDown.Items = FilesMun;
                app.SelectfileSADropDown.Value = FileName_StudyArea;

                MunShapeFields = string( [{shapeinfo(FileName_StudyArea).Attributes.Name}, {"None of these"}] );
                app.FieldMunicipalityDropDown.Items = MunShapeFields;

                if isempty(MunFieldName); MunFieldName = "None of these"; end
                app.FieldMunicipalityDropDown.Value = MunFieldName;

                set(app.UITableMun, 'Data',reshape(MunSel, numel(MunSel), 1))

                cd(fold_var)
            end

            if exist('LandUsesVariables.mat', 'file')
                load('UserStudyArea_Answers.mat', 'FileNameLandUses', 'LandUsesFieldName')
                load('LandUsesVariables.mat')

                cd(fold_raw_land_uses)

                app.LANDUSEPanel.Enable              = 'on';
                app.SelectfileLUDropDown.Enable      = 'on';
                app.LandUseMenu.Enable               = 'on';
                app.AllMenu.Enable                   = 'on';
                app.ImportLUButton.Enable            = 'on';
                app.SelectButton.Enable              = 'on';
                app.SelectDTMfilesButton.Enable      = 'on';
                app.FieldLandUseDropDown.Enable      = 'on';
                app.FieldLandUseDropDownLabel.Enable = 'on';

                FilesLand = {dir('*.shp').name};
                app.SelectfileLUDropDown.Items = FilesLand;
                app.SelectfileLUDropDown.Value = FileNameLandUses;

                LandUsesShapeFields = string( [{shapeinfo(FileNameLandUses).Attributes.Name}, {"None of these"}] );
                app.FieldLandUseDropDown.Items = LandUsesShapeFields;
                app.FieldLandUseDropDown.Value = LandUsesFieldName;

                try 
                    LandToRemStrings = [AllLandUnique(IndexLandUsesToRemove)]';
                    set(app.UITableLURem, 'Data',LandToRemStrings)
                    app.ExcludedMenu.Enable = 'on';
                end

                cd(fold_var)       
            end


            if exist('UserMorph_Answers.mat', 'file')
                load('UserMorph_Answers.mat')

                app.SelectDTMfilesButton.Enable     = 'on';
                app.OrthophotoCheckBox.Enable       = 'on';
                app.SwitchSoil.Enable               = 'on';
                app.RunLito.Enable                  = 'on';
                app.MorphologyMenu.Enable           = 'on';
                app.ElevationMenu.Enable            = 'on';
                app.SlopeMenu.Enable                = 'on';
                app.AspectMenu.Enable               = 'on';
                app.RunDTMButton.Enable             = 'on';
                app.DISTANCESTOPOLYGONSPanel.Enable = 'on';
                app.SelectfileDropDown.Enable         = 'on';
                app.EvaluateButton.Enable           = 'on';
                app.FromLandUseButton.Enable        = 'on';
                
                set(app.UITableDTM, 'Data',FileName_DTM)

                if AnswerChangeDTMResolution == 1
                    app.ChangeResolutionCheckBox.Enable = 'on';
                    app.ChangeResolutionCheckBox.Value  = 1;
                    app.NewResEditField.Enable          = 'on';
                    app.NewResEditField.Editable        = 'on';
                    app.NewResEditField.Value           = NewDx;
                end

                if exist('Orthophoto.mat', 'file')
                    app.OrthophotoCheckBox.Value = 1;
                end
                
                cd(fold_raw_road)
                FilesRoad = {dir('*.shp').name};
                app.SelectfileDropDown.Items = FilesRoad; 
                cd(fold_var)
            end


            if exist('UserSoil_Answers.mat', 'file')
                load('UserSoil_Answers.mat')

                if AnswerAttributionSoilParameter == 1
                    app.SwitchSoil.Value                = 'Map';
                    app.ChoosefileLitDropDown.Enable    = 'on';
                    app.SelectFieldDropDown.Enable      = 'on';
                    app.SelectFieldDropDownLabel.Enable = 'on';
                    app.RunLito.Enable                  = 'on';
                    app.AssociationButton.Visible       = 'on';
                    app.SoilMenu.Enable                 = 'on';
                    app.LithologyMapMenu.Enable         = 'on';
                    
                    cd(fold_raw_lit)
                    FilesLit = {dir('*.shp').name};
                    app.ChoosefileLitDropDown.Items = FilesLit;
                    app.ChoosefileLitDropDown.Value = FileName_Lithology;

                    LitShapeFields = string( [{shapeinfo(FileName_Lithology).Attributes.Name}, {"None of these"}] );
                    app.SelectFieldDropDown.Items = LitShapeFields;
                    app.SelectFieldDropDown.Value = LitFieldName;
                    
                    cd(fold_var)
                    try 
                        load('LUDSCMapParameters.mat', 'SoilAssociation')
                        app.EffectiveCohesionMenu.Enable     = 'on';
                        app.FrictionAngleMenu.Enable         = 'on';
                        app.DrainageCoefficientMenu.Enable   = 'on';
                        app.AParameterMenu.Enable            = 'on';
                        app.SoilUnitsMenu.Enable             = 'on';
                        app.VEGETATIONPARAMETERSPanel.Enable = 'on';
                        app.SwitchVeg.Enable                 = 'on';
                        app.LandUseCheckBox.Enable           = 'on';
                        app.RunVeg.Enable                    = 'on';
                        
                        LUStrings = [SoilAssociation{:, 'Acronym'}, ...
                                     string(SoilAssociation{:, 'UC'})];
                        app.UITableLitho.Data = LUStrings;
                    end
                else
                    app.SoilMenu.Enable                  = 'on';
                    app.EffectiveCohesionMenu.Enable     = 'on';
                    app.FrictionAngleMenu.Enable         = 'on';
                    app.DrainageCoefficientMenu.Enable   = 'on';
                    app.AParameterMenu.Enable            = 'on';
                    app.VEGETATIONPARAMETERSPanel.Enable = 'on';
                    app.SwitchVeg.Enable                 = 'on';
                    app.LandUseCheckBox.Enable           = 'on';
                    app.RunVeg.Enable                    = 'on';
                end
            end

            if exist([fold_var,sl,'UserVeg_Answers.mat'], 'file')
                load([fold_var,sl,'UserVeg_Answers.mat'], 'AnswerAttributionVegetationParameter')
                
                app.VegetationMenu.Enable            = 'on';
                app.VEGETATIONPARAMETERSPanel.Enable = 'on';
                app.ChoosefileVegDropDown.Enable     = 'on';
                app.SelectFieldVegDropDown.Enable    = 'on';
                app.RunVeg.Enable                    = 'on';
                app.AssociationButtonVeg.Enable      = 'on';
                app.MapMenu.Enable                   = 'on';

                if exist('AnswerAttributionVegetationParameter', 'var')
                    app.SwitchVeg.Value = 'Uniform';
                    if AnswerAttributionVegetationParameter == 1
                        cd(fold_raw_veg)
                        app.SwitchVeg.Value = 'Map';
                    elseif AnswerAttributionVegetationParameter == 2 
                        cd(fold_raw_land_uses)
                        app.LandUseCheckBox.Enable = 'on';
                        app.LandUseCheckBox.Value  = 1;
                    end
    
                    if AnswerAttributionVegetationParameter ~= 0
                        load([fold_var,sl,'UserVeg_Answers.mat'], 'VegFieldName','FileName_Vegetation')
                        FilesVeg = {dir('*.shp').name};  
                        app.ChoosefileVegDropDown.Items   = FilesVeg;
                        app.ChoosefileVegDropDown.Value   = FileName_Vegetation;
                        app.SelectFieldVegDropDown.Enable = 'on';
                        VegShapeFields = string( [{shapeinfo(FileName_Vegetation).Attributes.Name}, {"None of these"}] );
                        app.SelectFieldVegDropDown.Items = VegShapeFields;
                        app.SelectFieldVegDropDown.Value = VegFieldName;
                    end
                end
                
                cd(fold_var)
                if exist('VUDVCMapParameters.mat', 'file')
                    load('VUDVCMapParameters.mat', 'VegAssociation')
                    app.RootcohesionMenu.Enable = 'on';
                    app.ThroughfallMenu.Enable  = 'on';
                    
                    VegStr = [VegAssociation{:, 'Acronym'}, ...
                              string(VegAssociation{:, 'UC'})];
                    app.UITableVeg.Data = VegStr;
                end
             end

             if exist('UserTimeSens_Answers.mat', 'file')
                load('UserTimeSens_Answers.mat', 'AnalysisCase','AnswerTypeRec', ...
                                                 'AnswerTypeFor','InterpDuration')
                
                app.RainfallMenu.Enable    = 'on';
                app.SwitchRain.Value       = AnalysisCase;
                app.SwitchTypeInterp.Value = InterpDuration;
                app.RecordsCheckBox.Value  = AnswerTypeRec;
                app.ForecastCheckBox.Value = AnswerTypeFor;

                if AnswerTypeRec == 1
                    app.RecordingsMenu.Enable = 'on';
                    RecAlreadyInterp = isprop(matfile('RainInterpolated.mat'), 'RainInterpolated');
                end

                if AnswerTypeFor == 1
                    app.ForecastMapMenu.Enable = 'on';
                end

                if strcmp(AnalysisCase, 'SLIP')
                    load('AnalysisInformation.mat', 'StabilityAnalysis')

                    app.HandinitialSrMenu.Enable   = 'on';
                    app.mEvaluationMenu.Enable     = 'on';
                    app.PIPAIGenericMenu.Enable = 'on';
                    app.UITableRainfall.ColumnName = 'Analyses timing';

                    AnalysesEvents = string(StabilityAnalysis{:,2});
                    app.UITableRainfall.Data = AnalysesEvents';
                else
                    load('GeneralRainfall.mat', 'RecDatesEndCommon')

                    app.UITableRainfall.Data = string(RecDatesEndCommon);
                end
             end

             if exist('UserDistances_Answers.mat', 'file')
                 load('UserDistances_Answers.mat', 'FileName_ObjDst')
                 load('Distances.mat', 'Distances')

                 app.UITableDstPoly.Enable = 'on';
                 app.SelectfileDropDown.Items = {FileName_ObjDst, 'None'};
                 app.SelectfileDropDown.Value = FileName_ObjDst;
                 app.UITableDstPoly.Data = Distances.Properties.VariableNames';
             end
             
             cd(fold0)
        end

        % Menu selected function: MapMenu
        function MapMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold0','fold_fig')
            
            Fig = app.SLIP;
            NumFigPlot = 1;

            try
                xyz_Plot_Map_VegetationParameters
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: RootcohesionMenu
        function RootcohesionMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold0','fold_fig')

            Fig = app.SLIP;

            NumFigPlot = 2;
            try
                xyz_Plot_Map_VegetationParameters
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow    
        end

        % Menu selected function: ThroughfallMenu
        function ThroughfallMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold0','fold_fig')

            Fig = app.SLIP;

            NumFigPlot = 3;
            try
                xyz_Plot_Map_VegetationParameters
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow    
        end

        % Button pushed function: SelectfilesButton
        function SelectfilesButtonPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'sl','fold0','fold_var','fold_raw','fold_raw_rain', ...
                               'fold_raw_rain_for','fold_raw_temp','fold_raw_temp_for')

            Fig = app.SLIP;
            
            if app.RecordsCheckBox.Value == 0 && app.ForecastCheckBox.Value == 0
                uialert(app.SLIP, 'The choice of the rainfall type is mandatory', 'Error')
                return
            elseif (strcmp(app.SwitchRain.Value,'SLIP') && ...
                    app.RecordsCheckBox.Value == 0          && ...
                    app.ForecastCheckBox.Value == 1)
                uialert(app.SLIP, 'Recordings are necessary for a forecast analysis', 'Error')
                return
            elseif (strcmp(app.SwitchRain.Value,'Other') && ...
                    app.RecordsCheckBox.Value == 1           && ...
                    app.ForecastCheckBox.Value == 1)
                uialert(app.SLIP, 'Please select just one rainfall type', 'Error')
                return
            end
            
            AnswerTypeRec = 0;
            if app.RecordsCheckBox.Value == 1
                AnswerTypeRec = 1;
            end

            AnswerTypeFor = 0;
            if app.ForecastCheckBox.Value == 1
                AnswerTypeFor = 1;
            end

            AnalysisCase   = app.SwitchRain.Value;
            InterpDuration = app.SwitchTypeInterp.Value;
            try
                D_TimeSensitive_Step1_DataReading

                switch AnalysisCase
                    case 'SLIP'
                        app.UITableRainfall.ColumnName = 'Analyses timing';
                        set(app.UITableRainfall, 'Data',string(AnalysisEvents)');
                    case 'Other'
                        app.UITableRainfall.ColumnName = 'Events to interpolate';
                        set(app.UITableRainfall, 'Data',string(RecDatesEndCommon));
                end
            catch me
                switch me.message
                    case 'DT 1'
                        uialert(app.SLIP, ['Forecast and recordings have ' ...
                                           'incopatible dates'], 'Error DT1')
                    otherwise
                        getReport(me)
                        uialert(app.SLIP,['Please see MATLAB command, an ' ...
                                          'error occurred!'], 'Generic Error')
                end
            end
            
            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: RecordingMapMenu
        function RecordingMapMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')
            
            Fig = app.SLIP;

            RainFallType = 1;
            try
                xyz_Plot_Map_Rain;
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: ForecastMapMenu
        function ForecastMapMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')

            Fig = app.SLIP;

            RainFallType = 0;
            try
                xyz_Plot_Map_Rain;
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: FSMap
        function FSMapMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold_res_fs','fold0','fold_fig')

            Fig = app.SLIP;

            try
                xyz_Plot_Map_FS;
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: PunctualMenu
        function PunctualMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold_res_fs','fold_fig')

            Fig = app.SLIP;

            cd(fold_var)
            if exist('InfoDetectedSoilSlips.mat', 'file')
                try
                    xyz_Plot_Graph_FSRainPunctual;
                catch me
                    getReport(me)
                    uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                       'an error occurred!'], 'Generic Error')
                end
            else
                uialert(app.SLIP, ['No detected soil slip! ' ...
                                   'Please run Extra -> Database -> Points Association'], ...
                                   'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: SusceptibilityMenu
        function SusceptibilityMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')

            Fig = app.SLIP;

            try
                F_Susceptibility;
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end
        end

        % Button pushed function: FromLandUseButton
        function FromLandUseButtonPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')

            if exist([fold_var,sl,'LandUsesVariables.mat'], 'file')
                load([fold_var,sl,'LandUsesVariables.mat'], 'AllLandUnique')

                SelLndUse = string(checkbox2(AllLandUnique, 'Title','Land use for distance:'));

                app.SelectfileDropDown.Value = 'None';
                app.UITableDstPoly.Enable    = 'on';
                app.UITableDstPoly.Data      = SelLndUse';
            else
                uialert(app.SLIP, 'No Land Uses have been imported', 'Error')
            end

        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')

            Fig = app.SLIP;

            FileName_ObjDst = app.SelectfileDropDown.Value;

            switch char(FileName_ObjDst)
                case 'None'
                    DistType = 'LandUse';
                    if isempty(app.UITableDstPoly.Data)
                        uialert(app.SLIP, 'At least one Land Use must be selected', 'Error')
                    end
                    SelLndUse = app.UITableDstPoly.Data;

                case ''
                    uialert(app.SLIP, 'Shapefile or Land Uses must be selected', 'Error')

                otherwise
                    DistType = 'ObjShape';

            end

            try
                C_Distances;

                app.UITableDstPoly.Data = Distances.Properties.VariableNames'; % ObjDstNms from C_Distances
                app.LampDist.Visible    = 'on';

            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: RoadMenu
        function RoadMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')
            
            IndRoad = 1;
            IndLandUse = 0;
            xyz_Plot_Map_Distance
        end

        % Menu selected function: SpecificLUMenu
        function SpecificLUMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')
            
            IndRoad = 0;
            IndLandUse = 1;
            xyz_Plot_Map_Distance
        end

        % Button pushed function: RemAssButton
        function RemAssButtonPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var')
            
            Answer = uiconfirm(app.SLIP, 'Are you sure to delete existing association?', ...
                               'Remove existing Vegetation Association', ...
                               'Options',{'Yes, thanks', 'No, for God!'}, ...
                               'DefaultOption',2);

            switch Answer
                case 'Yes, thanks'
                    cd(fold_var)
                    delete 'VegPolygonsStudyArea.mat';
                    delete 'VUDVCMapParameters.mat';
                    load('GridCoordinates.mat', 'xLongAll');
                    load('MorphologyParameters.mat', 'SlopeAll');
                    
                    SizeCoordinates = cellfun(@size, xLongAll, 'UniformOutput',false);
                    BetaStarAll     = cellfun(@cosd, SlopeAll, 'UniformOutput',false); 
                    RootCohesionAll = cellfun(@zeros, SizeCoordinates, 'UniformOutput',false);
        
                    VariablesVegPar = {'RootCohesionAll', 'BetaStarAll'};
                    save('VegetationParameters.mat', VariablesVegPar{:});
                case 'No, for God!'
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: SoilUnitsMenu
        function SoilUnitsMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold0','fold_fig')

            NumFigPlot = 4;
            xyz_Plot_Map_SoilParameters
        end

        % Menu selected function: RecordingTrendMenu
        function RecordingTrendMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')

            RainFallType = 2;
            xyz_Plot_Map_Rain
        end

        % Menu selected function: RiskAssessmentMenu
        function RiskAssessmentMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')

            Fig = app.SLIP;

            try
                F_WeightedRiskAnalysis
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end
        end

        % Menu selected function: PIPAIGenericMenu
        function PIPAIGenericMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_res_fs','sl')

            Fig = app.SLIP;

            if not(exist([fold_var,sl,'DatasetMLB.mat'], 'file'))
                uialert(app.SLIP,['Machine Learning require a dataset, please go in:' ...
                                  'Extra -> Database, and run Dataset Creation'],'Error');
            end
            
            try
                E_MLB_Step2_TrainingGeneric
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end
        end

        % Menu selected function: PerfAUCMenu
        function PerfAUCMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_res','fold_var')

            Fig = app.SLIP;

            try
                F_PerfSLIP
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end
        end

        % Menu selected function: PlotSettingsMenu
        function PlotSettingsMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var')

            plot_settings(fold_var)
        end

        % Menu selected function: MunicipalitiesMenu
        function MunicipalitiesMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_fig')

            Fig = app.SLIP;

            try
                xyz_Plot_Map_StudyArea
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: DrainageCoefficientMenu
        function DrainageCoefficientMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_fig')

            NumFigPlot = 5;
            xyz_Plot_Map_SoilParameters
        end

        % Menu selected function: AParameterMenu
        function AParameterMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_fig')

            NumFigPlot = 6;
            xyz_Plot_Map_SoilParameters
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            cd(app.FolderLabel.Text);
        end

        % Menu selected function: MLSLIPMenu
        function HybridSlipMLMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_res_fs','sl')

            Fig = app.SLIP;

            try
                F_HybridAnalysis
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: SoilPropertiesMenu
        function SoilPropertiesMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_fig','fold_raw','sl')

            Fig = app.SLIP;

            cd(fold_var)
            if exist('InfoDetectedSoilSlips.mat')
                Answer = uiconfirm(app.SLIP, ['If you made changes, you have to run again Table Creation ' ...
                                              '(go in: Extra -> Database)'], ...
                                   'Run Table Creation', ...
                                   'Options',{'Already done', 'Stop'}, ...
                                   'DefaultOption',2);

                if strcmp(Answer, 'Already done')
                    try
                        xyz_Plot_Map_InfoDetected
                    catch me
                        getReport(me)
                        uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                           'an error occurred!'], 'Generic Error')
                    end
                end

            else
                uialert(app.SLIP,['This function require to run Points Association and Info, ' ...
                                  'go in: Extra -> Database, and run both'],'Error');
            end

            figure(app.SLIP)
            drawnow
        end

        % Drop down opening function: SelectfileDropDown
        function SelectfileDropDownOpening(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_raw_road')
            
            cd(fold_raw_road)
            if isempty({dir('*.shp').name})
                InfrAns = uiconfirm(app.SLIP, strcat("No infrastructure shapefile in ",fold_raw_road), ...
                                        'No file in directory', 'Options','Search file');
                copyindirectory('shp', fold_raw_road, 'mode','multiple')
            end

            try
                FlnmInfr = {dir('*.shp').name, 'None'};
                app.SelectfileDropDown.Items = FlnmInfr;
                app.SelectfileDropDown.Value;

            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: PIPAIANNMenu
        function PIPAIANNMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_res_ml','sl')

            Fig = app.SLIP;

            if not(exist([fold_var,sl,'DatasetMLB.mat'], 'file'))
                uialert(app.SLIP,['Machine Learning require a dataset, please go in:' ...
                                  'Extra -> Database, and run Dataset Creation'],'Error');
            end

            try
                E_MLB_Step2_TrainingANNs
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: PointsAssociationMenu
        function PointsAssociationMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders')

            cd(fold_raw_det_ss)

            if isempty({dir('*.xlsx').name})
                Ans = uiconfirm(app.SLIP, strcat("No excel in ",fold_raw_det_ss), ...
                                'No file in directory', 'Options','Search file');
                copyindirectory('xlsx', fold_raw_det_ss, 'mode','multiple')
            end

            Fig = app.SLIP;
            
            try
                C_InfoDetectedSoilSlips
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: PointsInfoMenu
        function PointsInfoMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_user')

            Fig = app.SLIP;

            try
                D_InfoDetectedSoilSlips_Parameters
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: IntersectionextrapropertiesMenu
        function IntersectionextrapropertiesMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_raw','sl')

            Fig = app.SLIP;

            try
                C_SoilGridsProperties
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: DatasetCreationMenu
        function DatasetCreationMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','sl','fold_raw_det_ss','fold_raw_rain','fold_raw_temp')

            Fig = app.SLIP;

            DsetAns = checkbox2({'Dataset for LEP-AI', ...
                                 'Dataset for PIP-AI'}, 'DefInp',[1, 1], 'OutType','LogInd');

            try
                if DsetAns(1); E_MLA_Step1_DatasetCreation; end
                if DsetAns(2); E_MLB_Step1_DatasetCreation; end
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: FeaturesImportanceMenu
        function FeaturesImportanceMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_res_ml','sl')

            Fig = app.SLIP;

            try
                F_FeatureImportance
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: UpslopeAreaTWIMenu
        function UpslopeAreaTWIMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','sl')

            Fig = app.SLIP;

            try
                C_FlowRouting
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: HRUsMenu
        function HRUsMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','sl')

            Fig = app.SLIP;

            try
                D_HRUs
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: CurvaturesMenu
        function CurvaturesMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','sl')

            Fig = app.SLIP;

            try
                C_Curvatures
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Button pushed function: EventsButton
        function EventsButtonPushed(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','sl')

            Fig = app.SLIP;

            try
                D_TimeSensitive_Step2_Events
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: FeatureImportanceMenu
        function FeatureImportanceMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_fig','fold_res_ml','sl')

            Fig = app.SLIP;

            try
                xyz_Plot_Bar_FeatureImportance
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: FeaturesCorrelationMenu
        function FeaturesCorrelationMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_fig','sl')

            Fig = app.SLIP;

            try
                xyz_Plot_Stats_Correlations
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: PlotHRUsMenu
        function PlotHRUsMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_fig','sl')

            Fig = app.SLIP;

            try
                xyz_Plot_Stats_Correlations
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: PredictionsMenu
        function PredictionsMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_fig','sl')

            Fig = app.SLIP;

            try
                xyz_Plot_Map_PredictionsML
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: LandslidesPolygonsMenu
        function LandslidesPolygonsMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_raw_det_ss','fold_user')

            Fig = app.SLIP;

            SrcType = uiconfirm(Fig, 'Choose the type of source', ...
                                'Source landslide polygons', 'Options',{'Shp','Excel+Shp'});

            cd(fold_raw_det_ss)

            if isempty({dir('*.shp').name})
                Ans = uiconfirm(Fig, strcat("No shapefile in ",fold_raw_det_ss), ...
                                'No file in directory', 'Options','Search file');
                copyindirectory('shp', fold_raw_det_ss, 'mode','multiple')
            end

            switch SrcType
                case 'Shp'

                case 'Excel+Shp'
                    if isempty({dir('*.xlsx').name})
                        Ans = uiconfirm(Fig, strcat("No excel in ",fold_raw_det_ss), ...
                                        'No file in directory', 'Options','Search file');
                        copyindirectory('xlsx', fold_raw_det_ss, 'mode','multiple')
                    end

                otherwise
                    error('Source mode not recognized')
            end
            
            try
                B_DetectedSoilSlipsPolygons
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: OrthophotoMenu
        function OrthophotoMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_fig','sl')

            Fig = app.SLIP;

            if not(exist([fold_var,sl,'Orthophoto.mat'], 'file'))
                uialert(Fig, ['You did not process orthophoto yet, ' ...
                              'please do it and retry!'], 'No orthophoto!')
            else
                try
                    xyz_Plot_Map_Orthophoto
                catch me
                    getReport(me)
                    uialert(Fig, ['Please see MATLAB command, ' ...
                                  'an error occurred!'], 'Generic Error')
                end
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: MergeDEMsMenu
        function MergeDEMsMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_raw_dtm','sl')

            Fig = app.SLIP;

            try
                load([fold_var,sl,'StudyAreaVariables.mat'  ], 'StudyAreaPolygon')
                load([fold_var,sl,'GridCoordinates.mat'     ], 'xLongAll','yLatAll')
                load([fold_var,sl,'MorphologyParameters.mat'], 'ElevationAll')
                merge_dems(StudyAreaPolygon, xLongAll, yLatAll, ElevationAll, 'SavePath',fold_raw_dtm);
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: ForestMapMenu
        function ForestMapMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','sl')

            Fig = app.SLIP;

            try
                load([fold_var,sl,'StudyAreaVariables.mat'  ], 'StudyAreaPolygon')
                load([fold_var,sl,'GridCoordinates.mat'     ], 'xLongAll','yLatAll')
                load([fold_var,sl,'MorphologyParameters.mat'], 'RasterInfoGeoAll')

                [VegFileName, VegFilePath] = uigetfile({'*.tif;*.shp'; ...
                                                        '*.tif'; ...
                                                        '*.shp'}, 'Forest raster files', ...
                                                                  'MultiSelect','on');
                if not(iscell(VegFileName)); VegFileName = {VegFileName}; end
                VegFullFilePaths = strcat(VegFilePath, VegFileName);

                forested_areas(VegFullFilePaths, xLongAll, yLatAll, ...
                               RasterInfoGeoAll, 'PolyMask',StudyAreaPolygon)
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: ApplyPIPAIMenu
        function ApplyPIPAIMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_res_ml','sl')

            Fig = app.SLIP;

            try
                F_MLB_ApplyANN
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: PerfMLMetricsMenu
        function PerfMLMetricsMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_res_ml')

            Fig = app.SLIP;

            try
                F_QualityMetrics
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: PPRvsNPRMenu
        function PPRvsNPRMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_res_ml','fold_var')

            Fig = app.SLIP;

            try
                F_MLB_PPRvsNPR
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: LEPAIMenu
        function LEPAIMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_res_ml','sl')

            Fig = app.SLIP;

            if not(exist([fold_var,sl,'DatasetMLA.mat'], 'file'))
                uialert(app.SLIP,['Machine Learning require a dataset, please go in:' ...
                                  'Extra -> Database, and run Dataset Creation'],'Error');
            end
            
            try
                E_MLA_Step2_Training
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: ApplyLEPAIMenu
        function ApplyLEPAIMenuSelected(app, event)
            uialert(app.SLIP, 'Not yet implemented! (contact support)', 'Error');
        end

        % Menu selected function: TimeIndexMenu
        function TimeIndexMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_res_ml')

            Fig = app.SLIP;

            try
                G_MLB_TimeIndex
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: LandslidePathsMenu
        function LandslidePathsMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold_res_flow','fold_res_fs')

            Fig = app.SLIP;

            try
                G_LandslidePathEvolution
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: DatasetStatisticsMenu
        function DatasetStatisticsMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var')

            Fig = app.SLIP;

            try
                F_DatasetStatistics
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: AUCMenu
        function AUCMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold_res','fold_fig')

            Fig = app.SLIP;

            try
                xyz_Plot_Graph_PerfSLIP
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: MLMetricsMenu
        function MLMetricsMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold_res_ml','fold_fig')

            Fig = app.SLIP;

            try
                xyz_Plot_Graph_Perf
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: PDPMenu
        function PDPMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold_var','fold_res_ml','fold_fig')

            Fig = app.SLIP;

            try
                xyz_Plot_Graph_PDPs
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: PathsMenu
        function PathsMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_res_flow','fold_fig')

            Fig = app.SLIP;

            try
                xyz_Plot_Map_LandslidePaths

            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end

        % Menu selected function: PartialDependenceMenu
        function PartialDependenceMenuSelected(app, event)
            uialert(app.SLIP, ['To implement! Contact the support...'], 'Generic Error')
        end

        % Menu selected function: AverageValuesMenu
        function AverageValuesMenuSelected(app, event)
            cd(app.FolderLabel.Text)
            load('os_folders', 'fold0','fold_var','fold_raw')

            Fig = app.SLIP;

            AvgAns = checkbox2({'Static', 'Time Sensitive'}, 'DefInp',[1, 1], ...
                                            'Title',{'Type of data:'}, 'OutType','LogInd');

            try
                if AvgAns(1); B_AvgValues_Static; end
                if AvgAns(2); B_AvgValues_TimeSentitive; end
                
            catch me
                getReport(me)
                uialert(app.SLIP, ['Please see MATLAB command, ' ...
                                   'an error occurred!'], 'Generic Error')
            end

            figure(app.SLIP)
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SLIP = uifigure('Visible', 'off');
            app.SLIP.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.SLIP);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.ExtraMenu = uimenu(app.SLIP);
            app.ExtraMenu.Text = 'Extra';
            app.RastersMenu = uimenu(app.ExtraMenu);
            app.RastersMenu.Text = 'Rasters';
            app.MergeDEMsMenu = uimenu(app.RastersMenu);
            app.MergeDEMsMenu.MenuSelectedFcn = createCallbackFcn(app, @MergeDEMsMenuSelected, true);
            app.MergeDEMsMenu.Text = 'Merge DEMs';
            app.ForestMapMenu = uimenu(app.RastersMenu);
            app.ForestMapMenu.MenuSelectedFcn = createCallbackFcn(app, @ForestMapMenuSelected, true);
            app.ForestMapMenu.Text = 'Forest Map';
            app.IntersectionextrapropertiesMenu = uimenu(app.RastersMenu);
            app.IntersectionextrapropertiesMenu.MenuSelectedFcn = createCallbackFcn(app, @IntersectionextrapropertiesMenuSelected, true);
            app.IntersectionextrapropertiesMenu.Text = 'Intersection (extra properties)';
            app.ExtraMorphologyMenu = uimenu(app.ExtraMenu);
            app.ExtraMorphologyMenu.Text = 'Morphology';
            app.CurvaturesMenu = uimenu(app.ExtraMorphologyMenu);
            app.CurvaturesMenu.MenuSelectedFcn = createCallbackFcn(app, @CurvaturesMenuSelected, true);
            app.CurvaturesMenu.Text = 'Curvatures';
            app.HydrologyMenu = uimenu(app.ExtraMenu);
            app.HydrologyMenu.Text = 'Hydrology';
            app.UpslopeAreaTWIMenu = uimenu(app.HydrologyMenu);
            app.UpslopeAreaTWIMenu.MenuSelectedFcn = createCallbackFcn(app, @UpslopeAreaTWIMenuSelected, true);
            app.UpslopeAreaTWIMenu.Text = 'Upslope Area & TWI';
            app.HRUsMenu = uimenu(app.HydrologyMenu);
            app.HRUsMenu.MenuSelectedFcn = createCallbackFcn(app, @HRUsMenuSelected, true);
            app.HRUsMenu.Text = 'HRUs';
            app.DatabaseMenu = uimenu(app.ExtraMenu);
            app.DatabaseMenu.Text = 'Database';
            app.LandslidesPolygonsMenu = uimenu(app.DatabaseMenu);
            app.LandslidesPolygonsMenu.MenuSelectedFcn = createCallbackFcn(app, @LandslidesPolygonsMenuSelected, true);
            app.LandslidesPolygonsMenu.Text = 'Landslides Polygons';
            app.PointsAssociationMenu = uimenu(app.DatabaseMenu);
            app.PointsAssociationMenu.MenuSelectedFcn = createCallbackFcn(app, @PointsAssociationMenuSelected, true);
            app.PointsAssociationMenu.Text = 'Points Association';
            app.PointsInfoMenu = uimenu(app.DatabaseMenu);
            app.PointsInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @PointsInfoMenuSelected, true);
            app.PointsInfoMenu.Text = 'Points Info';
            app.AverageValuesMenu = uimenu(app.DatabaseMenu);
            app.AverageValuesMenu.MenuSelectedFcn = createCallbackFcn(app, @AverageValuesMenuSelected, true);
            app.AverageValuesMenu.Text = 'Average Values';
            app.DatasetCreationMenu = uimenu(app.DatabaseMenu);
            app.DatasetCreationMenu.MenuSelectedFcn = createCallbackFcn(app, @DatasetCreationMenuSelected, true);
            app.DatasetCreationMenu.Text = 'Dataset Creation';
            app.DatasetStatisticsMenu = uimenu(app.DatabaseMenu);
            app.DatasetStatisticsMenu.MenuSelectedFcn = createCallbackFcn(app, @DatasetStatisticsMenuSelected, true);
            app.DatasetStatisticsMenu.Text = 'Dataset Statistics';
            app.ModelingMenu = uimenu(app.SLIP);
            app.ModelingMenu.Text = 'Modeling';
            app.SLIPMenu = uimenu(app.ModelingMenu);
            app.SLIPMenu.Text = 'SLIP';
            app.HandinitialSrMenu = uimenu(app.SLIPMenu);
            app.HandinitialSrMenu.MenuSelectedFcn = createCallbackFcn(app, @HandinitialSrMenuSelected, true);
            app.HandinitialSrMenu.Enable = 'off';
            app.HandinitialSrMenu.Text = 'H and initial Sr';
            app.mEvaluationMenu = uimenu(app.SLIPMenu);
            app.mEvaluationMenu.MenuSelectedFcn = createCallbackFcn(app, @mEvaluationMenuSelected, true);
            app.mEvaluationMenu.Enable = 'off';
            app.mEvaluationMenu.Text = 'm evaluation';
            app.FSevaluationMenu = uimenu(app.SLIPMenu);
            app.FSevaluationMenu.MenuSelectedFcn = createCallbackFcn(app, @FSevaluationMenuSelected, true);
            app.FSevaluationMenu.Text = 'FS evaluation';
            app.MachineLearningMenu = uimenu(app.ModelingMenu);
            app.MachineLearningMenu.Text = 'Machine Learning';
            app.LEPAIMenu = uimenu(app.MachineLearningMenu);
            app.LEPAIMenu.MenuSelectedFcn = createCallbackFcn(app, @LEPAIMenuSelected, true);
            app.LEPAIMenu.Text = 'LEP-AI';
            app.PIPAIGenericMenu = uimenu(app.MachineLearningMenu);
            app.PIPAIGenericMenu.MenuSelectedFcn = createCallbackFcn(app, @PIPAIGenericMenuSelected, true);
            app.PIPAIGenericMenu.Enable = 'off';
            app.PIPAIGenericMenu.Text = 'PIP-AI (Generic)';
            app.PIPAIANNMenu = uimenu(app.MachineLearningMenu);
            app.PIPAIANNMenu.MenuSelectedFcn = createCallbackFcn(app, @PIPAIANNMenuSelected, true);
            app.PIPAIANNMenu.Text = 'PIP-AI (ANN)';
            app.PostModelingMenu = uimenu(app.SLIP);
            app.PostModelingMenu.Text = 'Post Modeling';
            app.MLAnalysisMenu = uimenu(app.PostModelingMenu);
            app.MLAnalysisMenu.Text = 'ML';
            app.ApplyLEPAIMenu = uimenu(app.MLAnalysisMenu);
            app.ApplyLEPAIMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyLEPAIMenuSelected, true);
            app.ApplyLEPAIMenu.Text = 'Apply LEP-AI';
            app.ApplyPIPAIMenu = uimenu(app.MLAnalysisMenu);
            app.ApplyPIPAIMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyPIPAIMenuSelected, true);
            app.ApplyPIPAIMenu.Text = 'Apply PIP-AI';
            app.HybridAnalysesMenu = uimenu(app.PostModelingMenu);
            app.HybridAnalysesMenu.Text = 'Hybrid Analyses';
            app.MLSLIPMenu = uimenu(app.HybridAnalysesMenu);
            app.MLSLIPMenu.MenuSelectedFcn = createCallbackFcn(app, @HybridSlipMLMenuSelected, true);
            app.MLSLIPMenu.Text = 'ML + SLIP';
            app.PerformanceMenu = uimenu(app.PostModelingMenu);
            app.PerformanceMenu.Text = 'Performance';
            app.PerfAUCMenu = uimenu(app.PerformanceMenu);
            app.PerfAUCMenu.MenuSelectedFcn = createCallbackFcn(app, @PerfAUCMenuSelected, true);
            app.PerfAUCMenu.Text = 'SLIP AUC';
            app.PerfMLMetricsMenu = uimenu(app.PerformanceMenu);
            app.PerfMLMetricsMenu.MenuSelectedFcn = createCallbackFcn(app, @PerfMLMetricsMenuSelected, true);
            app.PerfMLMetricsMenu.Text = 'ML Metrics';
            app.PPRvsNPRMenu = uimenu(app.PerformanceMenu);
            app.PPRvsNPRMenu.MenuSelectedFcn = createCallbackFcn(app, @PPRvsNPRMenuSelected, true);
            app.PPRvsNPRMenu.Text = 'PPR vs NPR';
            app.TimeIndexMenu = uimenu(app.PerformanceMenu);
            app.TimeIndexMenu.MenuSelectedFcn = createCallbackFcn(app, @TimeIndexMenuSelected, true);
            app.TimeIndexMenu.Text = 'Time Index';
            app.RiskMenu = uimenu(app.PostModelingMenu);
            app.RiskMenu.Text = 'Risk';
            app.SusceptibilityMenu = uimenu(app.RiskMenu);
            app.SusceptibilityMenu.MenuSelectedFcn = createCallbackFcn(app, @SusceptibilityMenuSelected, true);
            app.SusceptibilityMenu.Text = 'Susceptibility';
            app.RiskAssessmentMenu = uimenu(app.RiskMenu);
            app.RiskAssessmentMenu.MenuSelectedFcn = createCallbackFcn(app, @RiskAssessmentMenuSelected, true);
            app.RiskAssessmentMenu.Text = 'Risk Assessment';
            app.XAIMenu = uimenu(app.PostModelingMenu);
            app.XAIMenu.Text = 'XAI';
            app.FeaturesImportanceMenu = uimenu(app.XAIMenu);
            app.FeaturesImportanceMenu.MenuSelectedFcn = createCallbackFcn(app, @FeaturesImportanceMenuSelected, true);
            app.FeaturesImportanceMenu.Text = 'Features Importance';
            app.PartialDependenceMenu = uimenu(app.XAIMenu);
            app.PartialDependenceMenu.MenuSelectedFcn = createCallbackFcn(app, @PartialDependenceMenuSelected, true);
            app.PartialDependenceMenu.Text = 'Partial Dependence';
            app.EvolutionMenu = uimenu(app.PostModelingMenu);
            app.EvolutionMenu.Text = 'Evolution';
            app.LandslidePathsMenu = uimenu(app.EvolutionMenu);
            app.LandslidePathsMenu.MenuSelectedFcn = createCallbackFcn(app, @LandslidePathsMenuSelected, true);
            app.LandslidePathsMenu.Text = 'Landslide Paths';
            app.PlotMenu = uimenu(app.SLIP);
            app.PlotMenu.Text = 'Plot';
            app.SettingsMenu = uimenu(app.PlotMenu);
            app.SettingsMenu.Text = 'Settings';
            app.PlotSettingsMenu = uimenu(app.SettingsMenu);
            app.PlotSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotSettingsMenuSelected, true);
            app.PlotSettingsMenu.Text = 'Plot Settings';
            app.StudyAreaMenu = uimenu(app.PlotMenu);
            app.StudyAreaMenu.Enable = 'off';
            app.StudyAreaMenu.Text = 'Study Area';
            app.MunicipalitiesMenu = uimenu(app.StudyAreaMenu);
            app.MunicipalitiesMenu.MenuSelectedFcn = createCallbackFcn(app, @MunicipalitiesMenuSelected, true);
            app.MunicipalitiesMenu.Text = 'Municipalities';
            app.OrthophotoMenu = uimenu(app.StudyAreaMenu);
            app.OrthophotoMenu.MenuSelectedFcn = createCallbackFcn(app, @OrthophotoMenuSelected, true);
            app.OrthophotoMenu.Text = 'Orthophoto';
            app.LandUseMenu = uimenu(app.PlotMenu);
            app.LandUseMenu.Enable = 'off';
            app.LandUseMenu.Text = 'Land Use';
            app.AllMenu = uimenu(app.LandUseMenu);
            app.AllMenu.MenuSelectedFcn = createCallbackFcn(app, @AllMenuSelected, true);
            app.AllMenu.Enable = 'off';
            app.AllMenu.Text = 'All';
            app.ExcludedMenu = uimenu(app.LandUseMenu);
            app.ExcludedMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcludedMenuSelected, true);
            app.ExcludedMenu.Enable = 'off';
            app.ExcludedMenu.Text = 'Excluded';
            app.MorphologyMenu = uimenu(app.PlotMenu);
            app.MorphologyMenu.Enable = 'off';
            app.MorphologyMenu.Text = 'Morphology';
            app.ElevationMenu = uimenu(app.MorphologyMenu);
            app.ElevationMenu.MenuSelectedFcn = createCallbackFcn(app, @ElevationMenuSelected, true);
            app.ElevationMenu.Enable = 'off';
            app.ElevationMenu.Text = 'Elevation';
            app.SlopeMenu = uimenu(app.MorphologyMenu);
            app.SlopeMenu.MenuSelectedFcn = createCallbackFcn(app, @SlopeMenuSelected, true);
            app.SlopeMenu.Enable = 'off';
            app.SlopeMenu.Text = 'Slope';
            app.AspectMenu = uimenu(app.MorphologyMenu);
            app.AspectMenu.MenuSelectedFcn = createCallbackFcn(app, @AspectMenuSelected, true);
            app.AspectMenu.Enable = 'off';
            app.AspectMenu.Text = 'Aspect';
            app.SoilMenu = uimenu(app.PlotMenu);
            app.SoilMenu.Enable = 'off';
            app.SoilMenu.Text = 'Soil';
            app.LithologyMapMenu = uimenu(app.SoilMenu);
            app.LithologyMapMenu.MenuSelectedFcn = createCallbackFcn(app, @LithologyMapMenuSelected, true);
            app.LithologyMapMenu.Enable = 'off';
            app.LithologyMapMenu.Text = 'Lithology Map';
            app.EffectiveCohesionMenu = uimenu(app.SoilMenu);
            app.EffectiveCohesionMenu.MenuSelectedFcn = createCallbackFcn(app, @EffectiveCohesionMenuSelected, true);
            app.EffectiveCohesionMenu.Enable = 'off';
            app.EffectiveCohesionMenu.Text = 'Effective Cohesion';
            app.FrictionAngleMenu = uimenu(app.SoilMenu);
            app.FrictionAngleMenu.MenuSelectedFcn = createCallbackFcn(app, @FrictionAngleMenuSelected, true);
            app.FrictionAngleMenu.Enable = 'off';
            app.FrictionAngleMenu.Text = 'Friction Angle';
            app.DrainageCoefficientMenu = uimenu(app.SoilMenu);
            app.DrainageCoefficientMenu.MenuSelectedFcn = createCallbackFcn(app, @DrainageCoefficientMenuSelected, true);
            app.DrainageCoefficientMenu.Enable = 'off';
            app.DrainageCoefficientMenu.Text = 'Drainage Coefficient';
            app.AParameterMenu = uimenu(app.SoilMenu);
            app.AParameterMenu.MenuSelectedFcn = createCallbackFcn(app, @AParameterMenuSelected, true);
            app.AParameterMenu.Enable = 'off';
            app.AParameterMenu.Text = 'A Parameter';
            app.SoilUnitsMenu = uimenu(app.SoilMenu);
            app.SoilUnitsMenu.MenuSelectedFcn = createCallbackFcn(app, @SoilUnitsMenuSelected, true);
            app.SoilUnitsMenu.Enable = 'off';
            app.SoilUnitsMenu.Text = 'Soil Units';
            app.VegetationMenu = uimenu(app.PlotMenu);
            app.VegetationMenu.Enable = 'off';
            app.VegetationMenu.Text = 'Vegetation';
            app.MapMenu = uimenu(app.VegetationMenu);
            app.MapMenu.MenuSelectedFcn = createCallbackFcn(app, @MapMenuSelected, true);
            app.MapMenu.Enable = 'off';
            app.MapMenu.Text = 'Map';
            app.RootcohesionMenu = uimenu(app.VegetationMenu);
            app.RootcohesionMenu.MenuSelectedFcn = createCallbackFcn(app, @RootcohesionMenuSelected, true);
            app.RootcohesionMenu.Enable = 'off';
            app.RootcohesionMenu.Text = 'Root cohesion';
            app.ThroughfallMenu = uimenu(app.VegetationMenu);
            app.ThroughfallMenu.MenuSelectedFcn = createCallbackFcn(app, @ThroughfallMenuSelected, true);
            app.ThroughfallMenu.Enable = 'off';
            app.ThroughfallMenu.Text = 'Throughfall';
            app.DetectedMenu = uimenu(app.PlotMenu);
            app.DetectedMenu.Text = 'Detected';
            app.SoilPropertiesMenu = uimenu(app.DetectedMenu);
            app.SoilPropertiesMenu.MenuSelectedFcn = createCallbackFcn(app, @SoilPropertiesMenuSelected, true);
            app.SoilPropertiesMenu.Text = 'Soil Properties';
            app.RainfallMenu = uimenu(app.PlotMenu);
            app.RainfallMenu.Enable = 'off';
            app.RainfallMenu.Text = 'Rainfall';
            app.RecordingsMenu = uimenu(app.RainfallMenu);
            app.RecordingsMenu.Text = 'Recordings';
            app.RecordingMapMenu = uimenu(app.RecordingsMenu);
            app.RecordingMapMenu.MenuSelectedFcn = createCallbackFcn(app, @RecordingMapMenuSelected, true);
            app.RecordingMapMenu.Text = 'Recording Map';
            app.RecordingTrendMenu = uimenu(app.RecordingsMenu);
            app.RecordingTrendMenu.MenuSelectedFcn = createCallbackFcn(app, @RecordingTrendMenuSelected, true);
            app.RecordingTrendMenu.Text = 'Recording Trend';
            app.ForecastMapMenu = uimenu(app.RainfallMenu);
            app.ForecastMapMenu.MenuSelectedFcn = createCallbackFcn(app, @ForecastMapMenuSelected, true);
            app.ForecastMapMenu.Text = 'Forecast Map';
            app.PlotPerformanceMenu = uimenu(app.PlotMenu);
            app.PlotPerformanceMenu.Text = 'Performance';
            app.AUCMenu = uimenu(app.PlotPerformanceMenu);
            app.AUCMenu.MenuSelectedFcn = createCallbackFcn(app, @AUCMenuSelected, true);
            app.AUCMenu.Text = 'AUC';
            app.MLMetricsMenu = uimenu(app.PlotPerformanceMenu);
            app.MLMetricsMenu.MenuSelectedFcn = createCallbackFcn(app, @MLMetricsMenuSelected, true);
            app.MLMetricsMenu.Text = 'ML Metrics';
            app.DistanceMenu = uimenu(app.PlotMenu);
            app.DistanceMenu.Text = 'Distance';
            app.RoadMenu = uimenu(app.DistanceMenu);
            app.RoadMenu.MenuSelectedFcn = createCallbackFcn(app, @RoadMenuSelected, true);
            app.RoadMenu.Text = 'Road';
            app.SpecificLUMenu = uimenu(app.DistanceMenu);
            app.SpecificLUMenu.MenuSelectedFcn = createCallbackFcn(app, @SpecificLUMenuSelected, true);
            app.SpecificLUMenu.Text = 'Specific LU';
            app.FSMenu = uimenu(app.PlotMenu);
            app.FSMenu.Text = 'FS';
            app.FSMap = uimenu(app.FSMenu);
            app.FSMap.MenuSelectedFcn = createCallbackFcn(app, @FSMapMenuSelected, true);
            app.FSMap.Text = 'Map';
            app.PunctualMenu = uimenu(app.FSMenu);
            app.PunctualMenu.MenuSelectedFcn = createCallbackFcn(app, @PunctualMenuSelected, true);
            app.PunctualMenu.Text = 'Punctual';
            app.MLMenu = uimenu(app.PlotMenu);
            app.MLMenu.Text = 'ML';
            app.FeatureImportanceMenu = uimenu(app.MLMenu);
            app.FeatureImportanceMenu.MenuSelectedFcn = createCallbackFcn(app, @FeatureImportanceMenuSelected, true);
            app.FeatureImportanceMenu.Text = 'Feature Importance';
            app.FeaturesCorrelationMenu = uimenu(app.MLMenu);
            app.FeaturesCorrelationMenu.MenuSelectedFcn = createCallbackFcn(app, @FeaturesCorrelationMenuSelected, true);
            app.FeaturesCorrelationMenu.Text = 'Features Correlation';
            app.PredictionsMenu = uimenu(app.MLMenu);
            app.PredictionsMenu.MenuSelectedFcn = createCallbackFcn(app, @PredictionsMenuSelected, true);
            app.PredictionsMenu.Text = 'Predictions';
            app.PDPMenu = uimenu(app.MLMenu);
            app.PDPMenu.MenuSelectedFcn = createCallbackFcn(app, @PDPMenuSelected, true);
            app.PDPMenu.Text = 'PDP';
            app.PlotHydrologyMenu = uimenu(app.PlotMenu);
            app.PlotHydrologyMenu.Text = 'Hydrology';
            app.PlotHRUsMenu = uimenu(app.PlotHydrologyMenu);
            app.PlotHRUsMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotHRUsMenuSelected, true);
            app.PlotHRUsMenu.Text = 'HRUs';
            app.EvolutionMenu_2 = uimenu(app.PlotMenu);
            app.EvolutionMenu_2.Text = 'Evolution';
            app.PathsMenu = uimenu(app.EvolutionMenu_2);
            app.PathsMenu.MenuSelectedFcn = createCallbackFcn(app, @PathsMenuSelected, true);
            app.PathsMenu.Text = 'Paths';
            app.Image = uiimage(app.SLIP);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = '@Logo.png';
            app.SOILPARAMETERSButtonGroup = uibuttongroup(app.SLIP);
            app.SOILPARAMETERSButtonGroup.Title = 'SOIL PARAMETERS';
            app.SwitchSoil = uiswitch(app.SOILPARAMETERSButtonGroup, 'slider');
            app.SwitchSoil.Items = {'Uniform', 'Map'};
            app.SwitchSoil.ValueChangedFcn = createCallbackFcn(app, @SwitchSoilValueChanged, true);
            app.SwitchSoil.Enable = 'off';
            app.SwitchSoil.Value = 'Uniform';
            app.SelectFieldDropDownLabel = uilabel(app.SOILPARAMETERSButtonGroup);
            app.SelectFieldDropDownLabel.Enable = 'off';
            app.SelectFieldDropDownLabel.Text = 'Select Field';
            app.SelectFieldDropDown = uidropdown(app.SOILPARAMETERSButtonGroup);
            app.SelectFieldDropDown.Items = {};
            app.SelectFieldDropDown.Enable = 'off';
            app.SelectFieldDropDown.Value = {};
            app.ExcelcreatedLabel = uilabel(app.SOILPARAMETERSButtonGroup);
            app.ExcelcreatedLabel.Enable = 'off';
            app.ExcelcreatedLabel.Visible = 'off';
            app.ExcelcreatedLabel.Text = 'Excel created!';
            app.RunLito = uibutton(app.SOILPARAMETERSButtonGroup, 'push');
            app.RunLito.ButtonPushedFcn = createCallbackFcn(app, @RunLitoPushed, true);
            app.RunLito.Enable = 'off';
            app.RunLito.Text = 'Run';
            app.AssociationButton = uibutton(app.SOILPARAMETERSButtonGroup, 'push');
            app.AssociationButton.ButtonPushedFcn = createCallbackFcn(app, @AssociationButtonLithoPushed, true);
            app.AssociationButton.Visible = 'off';
            app.AssociationButton.Text = 'Association';
            app.SelectfileLabel_2 = uilabel(app.SOILPARAMETERSButtonGroup);
            app.SelectfileLabel_2.Enable = 'off';
            app.SelectfileLabel_2.Text = 'Select file';
            app.ChoosefileLitDropDown = uidropdown(app.SOILPARAMETERSButtonGroup);
            app.ChoosefileLitDropDown.Items = {};
            app.ChoosefileLitDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChoosefileLitDropDownOpening, true);
            app.ChoosefileLitDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosefileLitDropDownOpening, true);
            app.ChoosefileLitDropDown.Enable = 'off';
            app.ChoosefileLitDropDown.Value = {};
            app.FinishedLampSoil1 = uilamp(app.SOILPARAMETERSButtonGroup);
            app.FinishedLampSoil1.Visible = 'off';
            app.STUDYAREAPanel = uipanel(app.SLIP);
            app.STUDYAREAPanel.Title = 'STUDY AREA';
            app.StudyAreaLabel = uilabel(app.STUDYAREAPanel);
            app.StudyAreaLabel.Enable = 'off';
            app.StudyAreaLabel.Text = 'Select file';
            app.SelectfileSADropDown = uidropdown(app.STUDYAREAPanel);
            app.SelectfileSADropDown.Items = {};
            app.SelectfileSADropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectfileSADropDownOpening, true);
            app.SelectfileSADropDown.ValueChangedFcn = createCallbackFcn(app, @SelectfileSADropDownOpening, true);
            app.SelectfileSADropDown.Enable = 'off';
            app.SelectfileSADropDown.Value = {};
            app.FieldMunicipalityDropDownLabel = uilabel(app.STUDYAREAPanel);
            app.FieldMunicipalityDropDownLabel.Enable = 'off';
            app.FieldMunicipalityDropDownLabel.Text = 'Field Municipality';
            app.FieldMunicipalityDropDown = uidropdown(app.STUDYAREAPanel);
            app.FieldMunicipalityDropDown.Items = {};
            app.FieldMunicipalityDropDown.ValueChangedFcn = createCallbackFcn(app, @FieldMunicipalityDropDownValueChanged, true);
            app.FieldMunicipalityDropDown.Enable = 'off';
            app.FieldMunicipalityDropDown.Value = {};
            app.RunSAButton = uibutton(app.STUDYAREAPanel, 'push');
            app.RunSAButton.ButtonPushedFcn = createCallbackFcn(app, @RunSAButtonPushed, true);
            app.RunSAButton.Enable = 'off';
            app.RunSAButton.Text = 'Run';
            app.SelMunButton = uibutton(app.STUDYAREAPanel, 'push');
            app.SelMunButton.ButtonPushedFcn = createCallbackFcn(app, @SelMunButtonPushed, true);
            app.SelMunButton.Enable = 'off';
            app.SelMunButton.Text = 'Sel. Mun.';
            app.FinishedLampRun1 = uilamp(app.STUDYAREAPanel);
            app.FinishedLampRun1.Visible = 'off';
            app.WinCheckBox = uicheckbox(app.STUDYAREAPanel);
            app.WinCheckBox.Enable = 'off';
            app.WinCheckBox.Text = 'Win';
            app.LANDUSEPanel = uipanel(app.SLIP);
            app.LANDUSEPanel.Title = 'LAND USE';
            app.SelectfileLabel = uilabel(app.LANDUSEPanel);
            app.SelectfileLabel.Enable = 'off';
            app.SelectfileLabel.Text = 'Select file';
            app.SelectfileLUDropDown = uidropdown(app.LANDUSEPanel);
            app.SelectfileLUDropDown.Items = {};
            app.SelectfileLUDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectfileLUDropDownOpening, true);
            app.SelectfileLUDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectfileLUDropDownOpening, true);
            app.SelectfileLUDropDown.Enable = 'off';
            app.SelectfileLUDropDown.Value = {};
            app.FieldLandUseDropDownLabel = uilabel(app.LANDUSEPanel);
            app.FieldLandUseDropDownLabel.Enable = 'off';
            app.FieldLandUseDropDownLabel.Text = 'Field Land Use ';
            app.FieldLandUseDropDown = uidropdown(app.LANDUSEPanel);
            app.FieldLandUseDropDown.Items = {};
            app.FieldLandUseDropDown.Enable = 'off';
            app.FieldLandUseDropDown.Value = {};
            app.ImportLUButton = uibutton(app.LANDUSEPanel, 'push');
            app.ImportLUButton.ButtonPushedFcn = createCallbackFcn(app, @ImportLUButtonPushed, true);
            app.ImportLUButton.Enable = 'off';
            app.ImportLUButton.Text = 'Import';
            app.FinishedLamp = uilamp(app.LANDUSEPanel);
            app.FinishedLamp.Visible = 'off';
            app.SelectButton = uibutton(app.LANDUSEPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLUButtonPushed, true);
            app.SelectButton.Enable = 'off';
            app.SelectButton.Text = 'Select';
            app.LUFinishedLamp = uilamp(app.LANDUSEPanel);
            app.LUFinishedLamp.Visible = 'off';
            app.MORPHOLOGYPanel = uipanel(app.SLIP);
            app.MORPHOLOGYPanel.Title = 'MORPHOLOGY';
            app.ChangeResolutionCheckBox = uicheckbox(app.MORPHOLOGYPanel);
            app.ChangeResolutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangeResolutionCheckBoxValueChanged, true);
            app.ChangeResolutionCheckBox.Enable = 'off';
            app.ChangeResolutionCheckBox.Text = 'Change Resolution';
            app.EnterResolutionLabel = uilabel(app.MORPHOLOGYPanel);
            app.EnterResolutionLabel.Enable = 'off';
            app.EnterResolutionLabel.Text = '(New Res)';
            app.NewResEditField = uieditfield(app.MORPHOLOGYPanel, 'numeric');
            app.NewResEditField.Editable = 'off';
            app.NewResEditField.Enable = 'off';
            app.SelectDTMfilesButton = uibutton(app.MORPHOLOGYPanel, 'push');
            app.SelectDTMfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDTMfilesButtonPushed, true);
            app.SelectDTMfilesButton.Enable = 'off';
            app.SelectDTMfilesButton.Text = 'Select DTM file (s)';
            app.OrthophotoCheckBox = uicheckbox(app.MORPHOLOGYPanel);
            app.OrthophotoCheckBox.Enable = 'off';
            app.OrthophotoCheckBox.Text = 'Orthophoto';
            app.RunDTMButton = uibutton(app.MORPHOLOGYPanel, 'push');
            app.RunDTMButton.ButtonPushedFcn = createCallbackFcn(app, @RunDTMButtonPushed, true);
            app.RunDTMButton.Enable = 'off';
            app.RunDTMButton.Text = 'Run ';
            app.LampMorph = uilamp(app.MORPHOLOGYPanel);
            app.LampMorph.Visible = 'off';
            app.VEGETATIONPARAMETERSPanel = uibuttongroup(app.SLIP);
            app.VEGETATIONPARAMETERSPanel.Title = 'VEGETATION PARAMETERS';
            app.SwitchVeg = uiswitch(app.VEGETATIONPARAMETERSPanel, 'slider');
            app.SwitchVeg.Items = {'Uniform', 'Map'};
            app.SwitchVeg.ValueChangedFcn = createCallbackFcn(app, @SwitchVegValueChanged, true);
            app.SwitchVeg.Value = 'Uniform';
            app.LandUseCheckBox = uicheckbox(app.VEGETATIONPARAMETERSPanel);
            app.LandUseCheckBox.ValueChangedFcn = createCallbackFcn(app, @LandUseCheckBoxValueChanged, true);
            app.LandUseCheckBox.Enable = 'off';
            app.LandUseCheckBox.Text = 'Land Use';
            app.RunVeg = uibutton(app.VEGETATIONPARAMETERSPanel, 'push');
            app.RunVeg.ButtonPushedFcn = createCallbackFcn(app, @RunVegPushed, true);
            app.RunVeg.Text = 'Run';
            app.ExcelcreatedLabelVeg = uilabel(app.VEGETATIONPARAMETERSPanel);
            app.ExcelcreatedLabelVeg.Visible = 'off';
            app.ExcelcreatedLabelVeg.Text = 'Excel created!';
            app.SelectFieldDropDown_2Label = uilabel(app.VEGETATIONPARAMETERSPanel);
            app.SelectFieldDropDown_2Label.Enable = 'off';
            app.SelectFieldDropDown_2Label.Text = 'Select Field';
            app.SelectFieldVegDropDown = uidropdown(app.VEGETATIONPARAMETERSPanel);
            app.SelectFieldVegDropDown.Items = {};
            app.SelectFieldVegDropDown.Enable = 'off';
            app.SelectFieldVegDropDown.Value = {};
            app.SelectfileLabel_3 = uilabel(app.VEGETATIONPARAMETERSPanel);
            app.SelectfileLabel_3.Enable = 'off';
            app.SelectfileLabel_3.Text = 'Select file';
            app.ChoosefileVegDropDown = uidropdown(app.VEGETATIONPARAMETERSPanel);
            app.ChoosefileVegDropDown.Items = {};
            app.ChoosefileVegDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChoosefileVegDropDownOpening, true);
            app.ChoosefileVegDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosefileVegDropDownOpening, true);
            app.ChoosefileVegDropDown.Enable = 'off';
            app.ChoosefileVegDropDown.Value = {};
            app.RemAssButton = uibutton(app.VEGETATIONPARAMETERSPanel, 'push');
            app.RemAssButton.ButtonPushedFcn = createCallbackFcn(app, @RemAssButtonPushed, true);
            app.RemAssButton.Text = 'Rem. Ass.';
            app.FinishedLampVeg = uilamp(app.VEGETATIONPARAMETERSPanel);
            app.FinishedLampVeg.Visible = 'off';
            app.AssociationButtonVeg = uibutton(app.VEGETATIONPARAMETERSPanel, 'push');
            app.AssociationButtonVeg.ButtonPushedFcn = createCallbackFcn(app, @AssociationButtonVegPushed, true);
            app.AssociationButtonVeg.Enable = 'off';
            app.AssociationButtonVeg.Text = 'Association';
            app.TIMESENSITIVERAINFALLETCPanel = uipanel(app.SLIP);
            app.TIMESENSITIVERAINFALLETCPanel.Title = 'TIME SENSITIVE (RAINFALL ETC.)';
            app.SwitchRain = uiswitch(app.TIMESENSITIVERAINFALLETCPanel, 'slider');
            app.SwitchRain.Items = {'SLIP', 'Other'};
            app.SwitchRain.Value = 'SLIP';
            app.RecordsCheckBox = uicheckbox(app.TIMESENSITIVERAINFALLETCPanel);
            app.RecordsCheckBox.Text = 'Records';
            app.ForecastCheckBox = uicheckbox(app.TIMESENSITIVERAINFALLETCPanel);
            app.ForecastCheckBox.Text = 'Forecast';
            app.SelectfilesButton = uibutton(app.TIMESENSITIVERAINFALLETCPanel, 'push');
            app.SelectfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfilesButtonPushed, true);
            app.SelectfilesButton.Text = 'Select file (s)';
            app.InterpolationButton = uibutton(app.TIMESENSITIVERAINFALLETCPanel, 'push');
            app.InterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolationButtonPushed, true);
            app.InterpolationButton.Text = 'Interpolation';
            app.LampInterp = uilamp(app.TIMESENSITIVERAINFALLETCPanel);
            app.LampInterp.Visible = 'off';
            app.SwitchTypeInterp = uiswitch(app.TIMESENSITIVERAINFALLETCPanel, 'slider');
            app.SwitchTypeInterp.Items = {'Free', 'Daily'};
            app.SwitchTypeInterp.Value = 'Free';
            app.EventsButton = uibutton(app.TIMESENSITIVERAINFALLETCPanel, 'push');
            app.EventsButton.ButtonPushedFcn = createCallbackFcn(app, @EventsButtonPushed, true);
            app.EventsButton.Text = 'Events';
            app.TSLampInterp = uilamp(app.TIMESENSITIVERAINFALLETCPanel);
            app.TSLampInterp.Visible = 'off';
            app.UITableMun = uitable(app.SLIP);
            app.UITableMun.ColumnName = {'Selected municipalities'};
            app.UITableMun.RowName = {};
            app.UITableLURem = uitable(app.SLIP);
            app.UITableLURem.ColumnName = {'Land Use Removed'};
            app.UITableLURem.RowName = {};
            app.UITableDTM = uitable(app.SLIP);
            app.UITableDTM.ColumnName = {'DEM files'};
            app.UITableDTM.RowName = {};
            app.UITableLitho = uitable(app.SLIP);
            app.UITableLitho.ColumnName = {'Lithology'; 'Soil Class'};
            app.UITableLitho.RowName = {};
            app.CasestudyLabel = uilabel(app.SLIP);
            app.CasestudyLabel.Text = 'Case study: ';
            app.FolderLabel = uilabel(app.SLIP);
            app.FolderLabel.Text = 'Folder:';
            app.STARTButton = uibutton(app.SLIP, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.UITableVeg = uitable(app.SLIP);
            app.UITableVeg.ColumnName = {'Vegetation'; 'Veg Class'};
            app.UITableVeg.RowName = {};
            app.UITableRainfall = uitable(app.SLIP);
            app.UITableRainfall.ColumnName = {'Events to interpolate'};
            app.UITableRainfall.RowName = {};
            app.UITableDstPoly = uitable(app.SLIP);
            app.UITableDstPoly.ColumnName = {'Selected polygons'};
            app.UITableDstPoly.RowName = {};
            app.DISTANCESTOPOLYGONSPanel = uipanel(app.SLIP);
            app.DISTANCESTOPOLYGONSPanel.Title = 'DISTANCES TO POLYGONS';
            app.SelectfileDropDownLabel = uilabel(app.DISTANCESTOPOLYGONSPanel);
            app.SelectfileDropDownLabel.Enable = 'off';
            app.SelectfileDropDownLabel.Text = 'Select file';
            app.SelectfileDropDown = uidropdown(app.DISTANCESTOPOLYGONSPanel);
            app.SelectfileDropDown.Items = {};
            app.SelectfileDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectfileDropDownOpening, true);
            app.SelectfileDropDown.Enable = 'off';
            app.SelectfileDropDown.Value = {};
            app.EvaluateButton = uibutton(app.DISTANCESTOPOLYGONSPanel, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Enable = 'off';
            app.EvaluateButton.Text = 'Evaluate';
            app.FromLandUseButton = uibutton(app.DISTANCESTOPOLYGONSPanel, 'push');
            app.FromLandUseButton.ButtonPushedFcn = createCallbackFcn(app, @FromLandUseButtonPushed, true);
            app.FromLandUseButton.Enable = 'off';
            app.FromLandUseButton.Text = 'From Land Use';
            app.LampDist = uilamp(app.DISTANCESTOPOLYGONSPanel);
            app.LampDist.Visible = 'off';
            % Show the figure after all components are created
            app.SLIP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = M_SLIP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SLIP)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SLIP)
        end
    end
end